classdef Grids_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PolygonSidesDropDown       matlab.ui.control.DropDown
        PolygonSidesDropDownLabel  matlab.ui.control.Label
        version11Label             matlab.ui.control.Label
        ShowPanel                  matlab.ui.container.Panel
        RandomColorsCheckBox       matlab.ui.control.CheckBox
        RandomizeCheckBox          matlab.ui.control.CheckBox
        NodesNumerationCheckBox    matlab.ui.control.CheckBox
        BoundaryNodesCheckBox      matlab.ui.control.CheckBox
        VerticesCheckBox           matlab.ui.control.CheckBox
        ElementsCheckBox           matlab.ui.control.CheckBox
        f1EditField                matlab.ui.control.NumericEditField
        f1EditFieldLabel           matlab.ui.control.Label
        f2EditField                matlab.ui.control.NumericEditField
        f2EditFieldLabel           matlab.ui.control.Label
        x0EditField                matlab.ui.control.NumericEditField
        x0EditFieldLabel           matlab.ui.control.Label
        y0EditField                matlab.ui.control.NumericEditField
        y0EditFieldLabel           matlab.ui.control.Label
        XDivisionsSpinner          matlab.ui.control.Spinner
        XDivisionsSpinnerLabel     matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MemoryTimer % Description
    end
    methods (Access = private)
    
       function MemoryTimerFcn(app,~,~)
            % Get available memory
            [~,sys] = memory;
    
            % Calculate percentage and 
            usedmemory = sys.PhysicalMemory.Total-sys.PhysicalMemory.Available;
            totalmemory = sys.PhysicalMemory.Total;
            
            % Update YData in plot
            y = (usedmemory/totalmemory) * 100;
            ydata = app.PlotLine.YData;
            ydata = circshift(ydata,1);
            ydata(1) = y;
            app.PlotLine.YData = ydata;
       end        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BoundaryNodesCheckBox, ElementsCheckBox, 
        % NodesNumerationCheckBox, PolygonSidesDropDown, 
        % RandomColorsCheckBox, RandomizeCheckBox, VerticesCheckBox, 
        % XDivisionsSpinner, f1EditField, f2EditField, x0EditField, 
        % y0EditField
        function SuddivisioniXSpinnerValueChanged(app, event)
            
        cla(app.UIAxes)
        x0=app.x0EditField.Value; y0=app.y0EditField.Value;
        f1=app.f1EditField.Value; f2=app.f2EditField.Value;  
        xmin=x0; xmax=x0+f1;  
        ymin=y0; ymax=y0+f2;  
        
        suddx=ceil(app.XDivisionsSpinner.Value);
        if suddx==0
         suddx=1;
        end
        
        if app.PolygonSidesDropDown.Value == '5'
            n=5;
            if suddx==1
              suddx=2;
            end
            alpha = 2*pi/n; %angle between two vertices
            theta = pi/2; %rotation angle
            
            d = f1/(suddx-1); %pentagon diagonal, biggest dimension 
            r_vertice = d/(2*sin(alpha));
            a = r_vertice*cos(alpha/2);
            suddy = ceil(f2/(a+r_vertice));
            
            x=zeros(1,n);
            y=zeros(1,n);
            
            x_centro=xmin;
            y_centro=ymax-(r_vertice-tan(alpha/2)*d/2);
            
            indelem=0;
            elem_x=cell(suddx*suddy+(suddx-1)*(suddy-1),1);
            elem_y=cell(suddx*suddy+(suddx-1)*(suddy-1),1);
            elem=cell(suddx*suddy+(suddx-1)*(suddy-1),1);
            
            dx=f1/suddx;
            dy=f2/suddy;
            hold(app.UIAxes,'on');
            for i=1:suddy
                for j=1:suddx       
                    indelem=indelem+1;
                    %creates the pentagon, theta is the rotation
                    for k=1:n       
                       x(k)=x_centro+r_vertice*cos(alpha*(k-1)+theta);
                       y(k)=y_centro+r_vertice*sin(alpha*(k-1)+theta);
                    end
                    %reordering vertices positions for upside-down pentagons
                    if rem(i,2)==0
                        x=[x(4:end) x(1:3)];
                        y=[y(4:end) y(1:3)];
                    end  
                    %CREATION AND MORPHING OF SQAURES CONTAINING PENTAGONS 
                    if rem(i,2)==0 %even row
                        if j<suddx && i<suddy %exclude last square to the right outside of the boundary
                            if y(4)>ymin %if the square is not completely outside                    
                                if j==1
                                    x_q=[x(4), x(3), x(4), x(4)+abs(x(3)-x(4))];
                                    y_q=[y(4), ymax-dy*i, y(3)-abs(y(4)-y(3)), y(3)];  
                                    elem_x{indelem+suddx,:}=x_q;
                                    elem_y{indelem+suddx,:}=y_q;
                                elseif j==suddx-1
                                    x_q=[x(4), x(3), x(4), x(4)+abs(x(3)-x(4))];
                                    y_q=[y(4), y(3), y(3)-abs(y(4)-y(3)), ymax-dy*i];  
                                    elem_x{indelem+suddx,:}=x_q;
                                    elem_y{indelem+suddx,:}=y_q;
                                else
                                    x_q=[x(4), x(3), x(4), x(4)+abs(x(3)-x(4))];
                                    y_q=[y(4), y(3), y(3)-abs(y(4)-y(3)), y(3)];  
                                    elem_x{indelem+suddx,:}=x_q;
                                    elem_y{indelem+suddx,:}=y_q;
                                end
                            end
                        end
                    else
                        if j<suddx && i<suddy
                            if y(end)>ymin
                                if i==suddy-1  
                                    x_q=[x(end), x(end-1), xmin+dx*j, x(end)+abs(x(end)-x(end-1))];
                                    y_q=[y(end), y(end-1), ymin, y(end-1)];
                                    elem_x{indelem+suddx,:}=x_q;
                                    elem_y{indelem+suddx,:}=y_q;
                                elseif i==1
                                    x_q=[xmin+dx*j, x(end-1), x(end), x(end)+abs(x(end)-x(end-1))];
                                    y_q=[y(end), y(end-1), y(end-1)-abs(y(end)-y(end-1)), y(end-1)];
                                    elem_x{indelem+suddx,:}=x_q;
                                    elem_y{indelem+suddx,:}=y_q;
                                else
                                    x_q=[x(end), x(end-1), x(end), x(end)+abs(x(end)-x(end-1))];
                                    y_q=[y(end), y(end-1), y(end-1)-abs(y(end)-y(end-1)), y(end-1)];
                                    elem_x{indelem+suddx,:}=x_q;
                                    elem_y{indelem+suddx,:}=y_q;
                                end
                            end
                        end
                    end
                    
                    %MODIFIYNG PENTAGONAL SHAPES TO HAVE EQUAL SIDES LENGHTS
                    if rem(i,2)==0
                        if i>1 && i<suddy
                            if j==1
                                x=[xmin xmin x(4:5)];
                                y=[ymax-dy*(i-1) ymax-dy*i y(4:5)];
                            elseif j==suddx
                                x=[x(1:2) xmax xmax];
                                y=[y(1:2) ymax-dy*i ymax-dy*(i-1)];
                            end
                        elseif i==suddy
                            if j==1
                                x=[xmin xmin xmin+dx x(end)];
                                y=[ymin+dy ymin ymin y(end)];
                            elseif j==suddx
                                x=[x(1) xmax-dx xmax xmax];
                                y=[y(1) ymin ymin ymin+dy];
                            else
                                y(2)=ymin;
                                y(4)=ymin;
                                y(3)=[];
                                x(3)=[];
                                x(2)=xmin+dx*(j-1);
                                x(3)=xmin+dx*j;
                            end
                        end
                    else
                        if i==1
                            if j==1
                                x=[xmin xmin x(4), xmin+dx];
                                y=[y(2) ymax-dy y(4:end)];
                            elseif j==suddx
                                x=[xmax xmax-dx x(3) xmax];
                                y=[ymax y(2) y(3) ymax-dy];
                            else
                                y(1)=[];
                                x=[xmin+dx*(j-1) x(3:end-1) xmin+dx*j]; 
                            end
                        elseif i==suddy
                            if j==1
                                x=[x(1) xmin xmin+dx x(end)];
                                y=[ymin+dy ymin ymin y(end)];
                            elseif j==suddx
                                x=[xmax x(2) xmax-dx xmax];
                                y=[ymin+dy y(2) ymin ymin];
                            else
                                x=[x(1) x(2) xmin+dx*(j-1) xmin+dx*j x(end)];
                                y=[y(1) y(2) ymin ymin y(end)];
                            end
                        else
                            if j==1
                                x=[x(1) xmin x(4) x(end)];
                                y=[ymax-dy*(i-1) ymax-dy*i y(4) y(5)];
                            elseif j==suddx
                                x=[x(1:3) xmax];
                                y=[ymax-dy*(i-1) y(2:3) ymax-dy*i];
                            end
                        end
                    end
                    
                    elem_x{indelem,:}=x;
                    elem_y{indelem,:}=y;
                    if indelem==1
                      nodi_x = x;
                      nodi_y = y;
                    else
                      nodi_x = [nodi_x x];
                      nodi_y = [nodi_y y];
                    end
                    if j==suddx && i<suddy
                        indelem=indelem+suddx-1;
                    end
                    x_centro=x_centro+d;
                end
                if rem(i,2)==0
                    theta=pi/2;
                    y_centro=y_centro-2*r_vertice;
                else       
                    theta=3*pi/2;
                    y_centro=y_centro-2*a;
                end
                x_centro=xmin; 
            end
        elseif app.PolygonSidesDropDown.Value == '6'
            n=6;
            d=0.2;

            alpha = 2*pi/n; %angle between two vertices
            theta = pi/2; %rotation angle
            r_lato = (f1/(suddx))/2;
            r_vertice = r_lato/cos(alpha/2);
            suddy = ceil(f2/((3*r_vertice)/2));
            
            x_centro=xmin+r_lato;
            y_centro=ymax-r_vertice/2;
            
            indelem=0;
            elem_x=cell(suddx*suddy,1);
            elem_y=cell(suddx*suddy,1);
            elem=cell(suddx*suddy,1);
            
            ultimo_nodo=((y_centro+r_vertice)-(3*r_vertice/2)*(suddy-1)-r_vertice/2);
            if ultimo_nodo-ymin<r_vertice/4 && suddx>1
                suddy=suddy-1;
            end
            
            dy=f2/suddy;
            %% CREATION HEXAGONAL GRID
            
            for i=1:suddy
                for j=1:suddx 
                    indelem=indelem+1;
                    %create hexagon, theta is the rotation angle
                    for k=1:n       
                       x(k)=x_centro+r_vertice*cos(alpha*(k-1)+theta);
                       y(k)=y_centro+r_vertice*sin(alpha*(k-1)+theta);
                    end
                    if i==1
                        if j==1
                            y(3)=ymax-dy;
                            if suddx==1
                              y(5)=y(3);
                            end
                        elseif j==suddx
                            y(end-1)=ymax-dy;
                        end
                        x(1)=[];
                        y(1)=[];
                    elseif i==suddy  
                        if rem(suddy,2)==0            
                           x=[x(end), x(1), x(1), x(end)+r_lato, x(end)+r_lato];
                           y=[y(end), y(1), ymin, ymin, y(1)]; 
                           if j==1
                            y(2)=ymax-dy*(i-1);
                            if suddx==1
                              y(end)=y(2);
                            end
                           elseif j==suddx
                               y(end)=ymax-dy*(i-1);
                           end
                        else  
                          y(3)=ymin;
                          y(5)=ymin;
                          y(4)=[];
                          x(4)=[];  
                          if j==1
                            y(2)=ymax-dy*(i-1);
                           elseif j==suddx
                               y(end)=ymax-dy*(i-1);
                           end
                        end
                    else
                        if j==1
                            if rem(i,2)==0
                              x=[x(1), x(4:end)];
                              y=[ymax-dy*(i-1), ymax-dy*i, y(5:end)];
                            else          
                              y=[y(1), ymax-dy*(i-1), ymax-dy*i, y(4:end)];
                            end
                        elseif j==suddx && rem(i,2)==1
                            y(end)=ymax-dy*(i-1); 
                            y(end-1)=ymax-dy*i;
                        end
                    end
                    
                    
                    elem_x{indelem,:}=x;
                    elem_y{indelem,:}=y;
                    if indelem==1
                      nodi_x = x;
                      nodi_y = y;
                    else
                      nodi_x = [nodi_x x];
                      nodi_y = [nodi_y y];
                    end
                    x_centro=x_centro+2*r_lato;
                end
                
                if rem(i,2)==0 && i<suddy
                    indelem=indelem+1;
                        for k=1:n       
                           x(k)=x0+(x_centro-xmin)+r_vertice*cos(alpha*(k-1)+theta);
                           y(k)=y0+(y_centro-ymin)+r_vertice*sin(alpha*(k-1)+theta);
                        end 
                        y(1)=ymax-dy*(i-1); 
                        y(4)=ymax-dy*i;
                        x=x(1:4);
                        y=y(1:4);    
                      
                        elem_x{indelem,:}=x;
                        elem_y{indelem,:}=y;
                        nodi_x = [nodi_x x];
                        nodi_y = [nodi_y y];
                    
                    x_centro=xmin+r_lato;
                else          
                    x_centro=xmin;
                end
                y_centro=y_centro-(r_vertice+r_vertice/2);
            end
        else
            n=8;
            alpha = 2*pi/n; %angle between two vertices
            theta = pi/2+alpha/2; %rotation angle
            
            if suddx==1
                suddx=2; 
            end
            
            a=(f1/(suddx-1))/2;
            suddy = ceil(abs(ymax-a-ymin)/(2*a))+1;
            
            r_vertice = a/cos(alpha/2);
            r_lato = sin(alpha/2)*2*r_vertice;
            
            x=zeros(1,n);
            y=zeros(1,n);
            
            x_centro=xmin;
            y_centro=ymax;
            
            indelem=0;
            elem_x=cell(suddx*suddy+(suddx-1)*(suddy-1),1);
            elem_y=cell(suddx*suddy+(suddx-1)*(suddy-1),1);
            elem=cell(suddx*suddy+(suddx-1)*(suddy-1),1);
            
            dx=f1/suddx;
            dy=f2/suddy;
            
            hold(app.UIAxes,'on');
            for i=1:suddy
                for j=1:suddx            
                    indelem=indelem+1;
                    %create the octagon, theta is the rotation angle
                    for k=1:n       
                       x(k)=x_centro+r_vertice*cos(alpha*(k-1)+theta);
                       y(k)=y_centro+r_vertice*sin(alpha*(k-1)+theta);
                    end 
                    %CREATING RHOMBUSES
                    if j<suddx && i<suddy
                        x_q=[x(6) x(5) x(6) x(6)+abs(x(6)-x(5))];
                        y_q=[y(6) y(5) y(5)-abs(y(6)-y(5)) y(5)];
                        elem_x{indelem+suddx,:}=x_q;
                        elem_y{indelem+suddx,:}=y_q;
                    end
                    %MODIFYING OCTAGONS
                    if i==1
                        if j==1
                            x=[xmin xmin x(5) x(6) xmin+dx];
                            y=[ymax ymax-dy y(4) y(6) ymax];
                        elseif j==suddx
                            x=[xmax-dx x(3) x(4) xmax xmax];
                            y=[ymax y(3) y(4) ymax-dy ymax];
                        else
                            x=[xmin+dx*(j-1) x(3:6) xmin+dx*j];
                            y=[ymax y(3:6) ymax];
                        end          
                    elseif i==suddy
                        if j==1
                            x=[xmin xmin xmin+dx x(7:end)];
                            y=[ymin+dy ymin ymin y(7:end)];
                        elseif j==suddx
                            x=[xmax x(1:2) xmax-dx xmax];
                            y=[ymin+dy y(1:2) ymin ymin];
                        else
                            x=[x(1:2) xmin+dx*(j-1) xmin+dx*j x(7:end)];
                            y=[y(1:2) ymin ymin y(7:end)];
                        end 
                    else
                        if j==1
                            x=[xmin xmin x(5:end)];
                            y=[ymax-dy*(i-1) ymax-dy*i y(5:end)];
                        elseif j==suddx
                            x=[xmax x(1:4) xmax];
                            y=[ymax-dy*(i-1) y(1:4) ymax-dy*i];
                        end
                    end
                    
                    elem_x{indelem,:}=x;
                    elem_y{indelem,:}=y;
                    if indelem==1
                      nodi_x = x;
                      nodi_y = y;
                    else
                      nodi_x = [nodi_x x];
                      nodi_y = [nodi_y y];
                    end
                    if j==suddx && i<suddy
                        indelem=indelem+suddx-1;
                    end
                    
                    x_centro=x_centro+2*a;
                end
                x_centro=xmin;
                y_centro=y_centro-2*a;
            end
        end
       
        %% NODES NUMERATION
        linee_x=uniquetol(nodi_x); %quicksort + ascending order
        linee_y=fliplr(uniquetol(nodi_y)); %descending order
        nodi_unici=zeros(1,2); %indices of nodes laying on unique x and y lines
        count_nodi_unici=zeros(1,1); %nodes numeration for each vertex
        count_nodi_globali=0; %counter for global nodes
        xvert=zeros(1,1);
        yvert=zeros(1,1);
        
        for s=1:indelem
            for k=1:length(elem_x{s,:}) 
                count_nodi_globali=count_nodi_globali+1;
                %find node's position on unique lines with tolerance
                ind_pos_x=ismembertol(linee_x,elem_x{s,:}(1,k));
                ind_pos_y=ismembertol(linee_y,elem_y{s,:}(1,k));
                if s==1
                    count_nodi_unici(end+1)=count_nodi_globali;       
                    elem{s,:}(1,k)=count_nodi_unici(end);
                    xvert(end+1)= linee_x(ind_pos_x);
                    yvert(end+1)= linee_y(ind_pos_y);
                else
                    %if finds duplicates of nodes' indices
                    if find(ismember(nodi_unici,[find(ind_pos_x==1) find(ind_pos_y==1)],'rows'))
                        %find the index of the non repeated "original" node
                        index = find(ismember(nodi_unici,[find(ind_pos_x==1) find(ind_pos_y==1)],'rows'));
                        %insert the nueration already given to the existing node
                        count_nodi_unici(end+1)=count_nodi_unici(index(1));
                        %update the elements cell
                        elem{s,:}(1,k)=count_nodi_unici(end);
                        % decrease global counter
                        count_nodi_globali=count_nodi_globali-1;
                    else
                        count_nodi_unici(end+1)=count_nodi_globali;  
                        elem{s,:}(1,k)=count_nodi_unici(end);
                        %insert only non repeated coordinates
                        xvert(end+1)= linee_x(ind_pos_x);
                        yvert(end+1)= linee_y(ind_pos_y);
                    end
                end
                nodi_unici(end+1,:)=[find(ind_pos_x==1) find(ind_pos_y==1)]; 
            end
        end
        %remove first element for initialization
        xvert(1)=[];
        yvert(1)=[];
        
        %% PLOT CREATION
        %building boundary nodes
        nnode=length(xvert);
        j=0;
        b=zeros(1,1);
        for i=1:nnode
            if abs(xvert(i)-xmin)<=1e-10 || abs(xvert(i)-xmax)<=1e-10 || abs(yvert(i)-ymin)<=1e-10 || abs(yvert(i)-ymax)<=1e-10
                j=j+1;
                b(j)=i;
            else
                if app.RandomizeCheckBox.Value==1
                  if app.PolygonSidesDropDown.Value == '6'
                     xvert(i)=xvert(i)+d*2*(rand-0.5)*r_lato;  
                     yvert(i)=yvert(i)+d*2*(rand-0.5)*r_lato;
                  else
                     xvert(i)=xvert(i)+0.2*2*(rand-0.5)*a;  
                     yvert(i)=yvert(i)+0.2*2*(rand-0.5)*a;
                  end
                end
            end
        end
        griglia.dirichlet=b(:);
        griglia.neuman=0;
        
        %draw elements with their nodes
        hold(app.UIAxes,'on');
        
        
        for iel=1:indelem
            xvertici=elem{iel,:};
            xv=xvert(xvertici);
            yv=yvert(xvertici);
            plot(app.UIAxes, [xv, xv(1)],[yv, yv(1)],'k','linewidth',1)
            if app.RandomColorsCheckBox.Value==1
               fill(app.UIAxes,xv,yv,[rand,rand,rand])
            end
            if app.ElementsCheckBox.Value==1
               h=text(app.UIAxes,mean(xv), mean(yv), {num2str(iel)});
               if app.RandomColorsCheckBox.Value==1
                   set(h,'color','w')
               else
                   set(h,'color','r')
               end
            end         
        end
        
        if app.VerticesCheckBox.Value==1
            for i=1:length(xvert)
               plot(app.UIAxes, xvert(i),yvert(i),'o');  
            end
        end
        if app.NodesNumerationCheckBox.Value==1
            for i=1:length(xvert)
               text(app.UIAxes, xvert(i)+0.02,yvert(i)+0.02, num2str(i));  
            end
        end
        if app.BoundaryNodesCheckBox.Value==1
           for i=1:j
             plot(app.UIAxes,xvert(b(i)),yvert(b(i)),'m*')
           end
        end
         
        griglia.elements=indelem;
        griglia.vertices=[xvert;yvert];
        griglia.bordo=b(:);
        axis(app.UIAxes, 'equal');
          function ButtonPushed(app, event)
            
            % Draw line
            drawline(app.UIAxes)
            
        end
        end

        % Callback function
        function x0EditFieldValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Polygons Grid')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.XDivisionsSpinnerLabel = uilabel(app.UIFigure);
            app.XDivisionsSpinnerLabel.Text = 'X Divisions';
            app.XDivisionsSpinner = uispinner(app.UIFigure);
            app.XDivisionsSpinner.Limits = [0 Inf];
            app.XDivisionsSpinner.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.y0EditField.Limits = [0 Inf];
            app.y0EditField.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.x0EditField.Limits = [0 Inf];
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.f2EditFieldLabel = uilabel(app.UIFigure);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.UIFigure, 'numeric');
            app.f2EditField.Limits = [0 Inf];
            app.f2EditField.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.f2EditField.Value = 1;
            app.f1EditFieldLabel = uilabel(app.UIFigure);
            app.f1EditFieldLabel.Text = 'f1';
            app.f1EditField = uieditfield(app.UIFigure, 'numeric');
            app.f1EditField.Limits = [0 Inf];
            app.f1EditField.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.f1EditField.Value = 1;
            app.ShowPanel = uipanel(app.UIFigure);
            app.ShowPanel.Title = 'Show';
            app.ElementsCheckBox = uicheckbox(app.ShowPanel);
            app.ElementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.ElementsCheckBox.Text = 'Elements';
            app.VerticesCheckBox = uicheckbox(app.ShowPanel);
            app.VerticesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.VerticesCheckBox.Text = 'Vertices';
            app.BoundaryNodesCheckBox = uicheckbox(app.ShowPanel);
            app.BoundaryNodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.BoundaryNodesCheckBox.Text = 'Boundary Nodes';
            app.NodesNumerationCheckBox = uicheckbox(app.ShowPanel);
            app.NodesNumerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.NodesNumerationCheckBox.Text = 'Nodes Numeration';
            app.RandomizeCheckBox = uicheckbox(app.ShowPanel);
            app.RandomizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.RandomizeCheckBox.Text = 'Randomize';
            app.RandomColorsCheckBox = uicheckbox(app.ShowPanel);
            app.RandomColorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.RandomColorsCheckBox.Text = 'Random Colors';
            app.version11Label = uilabel(app.UIFigure);
            app.version11Label.Text = 'version 1.1';
            app.PolygonSidesDropDownLabel = uilabel(app.UIFigure);
            app.PolygonSidesDropDownLabel.Text = 'Polygon Sides';
            app.PolygonSidesDropDown = uidropdown(app.UIFigure);
            app.PolygonSidesDropDown.Items = {'5', '6', '8'};
            app.PolygonSidesDropDown.ValueChangedFcn = createCallbackFcn(app, @SuddivisioniXSpinnerValueChanged, true);
            app.PolygonSidesDropDown.Value = '5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Grids_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end